classdef BrainAnxietySimulatorFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ShowMapCheckBox               matlab.ui.control.CheckBox
        AnxietyGauge                  matlab.ui.control.SemicircularGauge
        AnxityLevelLabel              matlab.ui.control.Label
        RunSimulationButton           matlab.ui.control.Button
        EvaluatedStressResponseTextArea  matlab.ui.control.TextArea
        EvaluatedStressResponseLabel  matlab.ui.control.Label
        FamiliarCheckBox              matlab.ui.control.CheckBox
        DurationSlider                matlab.ui.control.Slider
        DurationLabel                 matlab.ui.control.Label
        IntensitySlider               matlab.ui.control.Slider
        IntensitylevelLabel           matlab.ui.control.Label
        StimulusTypeDropDown          matlab.ui.control.DropDown
        stimulustypeLabel             matlab.ui.control.Label
        BrainAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        AmygdalaPatch     matlab.graphics.primitive.Rectangle
        HippocampusPatch  matlab.graphics.primitive.Rectangle
        PFCPatch          matlab.graphics.primitive.Rectangle
        HypothalamusPatch matlab.graphics.primitive.Rectangle

        % Popup map (created on demand)
        MapFigure matlab.ui.Figure = matlab.ui.Figure.empty
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % === 1) get a clean drawing canvas ===
            ax = app.BrainAxes;                     % take the UIAxes we added in Design View
            cla(ax);                                % clear anything old
            hold(ax,'on');                          % allow multiple shapes at once
            axis(ax,'equal');                       % same scale on X and Y (circles stay round)
            axis(ax,[0 100 0 100]);                 % simple coordinate system: 0..100 in both axes
            ax.XTick = [];
            ax.YTick = [];
            box(ax,'on');                           % show border
            t = title(ax,'BRAIN REGIONS');          % show title
            t.FontWeight = 'bold';
            t.HorizontalAlignment = 'center';


            % === 2) easy-to-tune layout numbers ===
            D    = 30;      % circle size (diameter) for all regions
            Ytop = 60;      % Y position (bottom-left) for the TOP row
            Ybot = 15;      % Y position (bottom-left) for the BOTTOM circle
            X1   = 5;       % X for LEFT circle  (top row)
            X2   = 35;      % X for MIDDLE circle (top row) -> also used to center bottom
            X3   = 65;      % X for RIGHT circle (top row)

            % === 3) draw TOP row: Amygdala, Hippocampus, PFC ===
            app.AmygdalaPatch = rectangle(ax, 'Position',[X1 Ytop D D], ...
                'Curvature',[1 1], 'EdgeColor','none', 'FaceColor',[0.85 0.85 0.85]);  % a circle
            text(ax, X1 + D/2, 95, 'Amygdala', 'HorizontalAlignment','center');        % label above

            app.HippocampusPatch = rectangle(ax, 'Position',[X2 Ytop D D], ...
                'Curvature',[1 1], 'EdgeColor','none', 'FaceColor',[0.85 0.85 0.85]);
            text(ax, X2 + D/2, 95, 'Hippocampus', 'HorizontalAlignment','center');

            app.PFCPatch = rectangle(ax, 'Position',[X3 Ytop D D], ...
                'Curvature',[1 1], 'EdgeColor','none', 'FaceColor',[0.85 0.85 0.85]);
            text(ax, X3 + D/2, 95, 'PFC', 'HorizontalAlignment','center');

            % === 4) draw BOTTOM centered circle: Hypothalamus ===
            % Use X2 so the bottom circle is centered under the middle top circle.
            app.HypothalamusPatch = rectangle(ax, 'Position',[X2 Ybot D D], ...
                'Curvature',[1 1], 'EdgeColor','none', 'FaceColor',[0.85 0.85 0.85]);
            text(ax, X2 + D/2, 10, 'Hypothalamus', 'HorizontalAlignment','center');    % label below

            % --- Anxiety Gauge: one-time setup when the app starts ---
            g = app.AnxietyGauge;                 % handle to the gauge component
            g.Limits = [0 4];                     % model range ~ 0..4
            g.MajorTicks = 0:0.5:4;               % big tick marks every 0.5
            g.MinorTicks = 0:0.25:4;              % small tick marks every 0.25
            g.Value = 0;                          % start needle at 0


        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)

            % Take the user input values
            stimulusType = app.StimulusTypeDropDown.Value;  % Type of stimulus selected
            intensity = app.IntensitySlider.Value;          % Intensity (1–10)
            duration = app.DurationSlider.Value;            % Exposure duration (1–10)
            isFamiliar = app.FamiliarCheckBox.Value;        % Is the stimulus familiar? (true/false)


            % Ensure integer values to avoid calculation glitches
            % This prevents cases like 9.999 missing the "10" rule or 5.1 being misclassified
            intensity = round(intensity);
            duration  = round(duration);


            % Hard rule: if intensity and duration are both max, always High

            forceHigh = (intensity == 10 && duration == 10);

            % Convert intensity and duration to scores (1-4)
            % These scores normalize raw values to 4-level scale
            %iScore stands for intensity score
            %dScore stands for duration score

            % Intensity score (1..4)
            if intensity <= 3
                iScore = 1;                %iScore stands for intensity score
            elseif intensity <= 5
                iScore = 2;
            elseif intensity <= 7
                iScore = 3;
            else
                iScore = 4;
            end

            % Duration score (1..4)
            if duration <= 3
                dScore = 1;                %dScore stands for duration score
            elseif duration <= 5
                dScore = 2;
            elseif duration <= 7
                dScore = 3;
            else
                dScore = 4;
            end

            %% Calculate amygdala response using weighted influence of intensity and duration.
            % Weights vary based on stimulus type:
            %  Olfactory - strong intensity weight
            %  Auditory - strong duration weight
            %  Visual - equal weights

            switch stimulusType
                case 'Olfactory'
                    intensity_Weight = 0.7;
                    duration_Weight = 0.3;
                case 'Auditory'
                    intensity_Weight = 0.3;
                    duration_Weight = 0.7;
                case 'Visual'
                    intensity_Weight = 0.5;
                    duration_Weight = 0.5;
                otherwise
                    intensity_Weight = 0.5;
                    duration_Weight = 0.5;  % default weights
            end

            % Weighted sum and rounding
            amygdala_raw = intensity_Weight * iScore + duration_Weight * dScore;
            amygdala = round(amygdala_raw);

            %% Calculate hippocampus response
            %assuming every stimulus is scary
            % a nice touch or idea for the future if i have extra time - to creat
            % another buttun for nice memories, that will relife anxity

            if isFamiliar
                if dScore + iScore <= 3
                    hippocampus = 0;
                elseif dScore + iScore <= 5
                    hippocampus = 1;
                else
                    hippocampus = 2;
                end
            else
                hippocampus = 0;
            end

            %% Calculate PFC regulation
            % The PFC regulates emotional response
            % Strong or long-lasting stimuli make regulation harder
            % We simulate this by inverting the intensity and duration scores:
            %   - High intensity (4) → Low regulation (0)
            %   - Low intensity (1) → High regulation (3)

            pfcIntensity = 4 - iScore;
            pfcDuration  = 4 - dScore;

            % Average the two values to get overall regulation ability
            % Using floor avoids over-rounding mid cases (e.g., 1.5 → 1 instead of 2)

            pfcRegulation = floor((pfcIntensity + pfcDuration) / 2);

            % PFC regulation policy:
            % - Stronger-than-amygdala -> meaningful down-regulation (0.5)
            % - Equal strength          -> gentle down-regulation (0.3) to avoid over-suppression at mid cases
            % - One step below          -> partial down-regulation (0.2)
            % - Otherwise               -> no effective regulation
            if pfcRegulation > amygdala
                pfc = 0.5;
            elseif pfcRegulation == amygdala
                pfc = 0.3;
            elseif pfcRegulation == amygdala - 1
                pfc = 0.2;
            else
                pfc = 0;
            end


            %%  Compute Hypothalamus Response
            % Its activation depends on emotional input minus regulation:
            %   - Strong amygdala/hippocampus → more activation
            %   - Strong PFC → less activation

            hypothalamus = amygdala + hippocampus - pfc;

            % Ensure no negative values (not biologically realistic)
            if hypothalamus < 0
                hypothalamus = 0;
            end

            % Floor rule: if either dimension is max (but not both), ensure at least Moderate
            % Rationale: a single extreme (intensity=10 OR duration=10) should produce >= Moderate arousal.

            oneIsMax = ( (intensity == 10 || duration == 10) && ~(intensity == 10 && duration == 10) );
            if oneIsMax
                hypothalamus = max(hypothalamus, 2.0);   % guarantee >= Moderate with current thresholds (<1.8 Low, <3.5 Moderate)
            end

            % Salience boost:
            % Salience: small extra push if one dimension is especially strong
            Salience = max(iScore, dScore);

            boost = 0;
            if oneIsMax
                boost = 0.3;      % one slider at 10 so softened boost because floor already applied ensured Moderate
            elseif Salience == 4
                boost = 0.4;      % case: salience = 4 → medium boost
            elseif Salience == 3
                boost = 0.2;      % case: salience = 3 → small boost
            else
                boost = 0;        % no additional boost (low salience)
            end

            % Add salience boost on top of the current hypothalamus value
            hypothalamus_final_score = hypothalamus + boost;

            % Update gauge needle; clamp score to [0,4]
            app.AnxietyGauge.Value = max(0, min(4, hypothalamus_final_score));


            %% FUN COLORS PART!!!
            % Update brain colors based on levels (0..1)
            % 1) Turn each value into a level between 0 and 1 (normalize)
            %    max(0, min(1, x)) keeps the value inside 0..1 (no negatives, no >1).
            amyLevel = max(0, min(1, amygdala/4));   % amygdala ranges 1..4
            hipLevel = max(0, min(1, hippocampus/2));   % hippocampus ranges 0..2
            pfcLevel = max(0, min(1, pfc/0.5)); % PFC inhibition ranges 0..0.5

            % 2) Define fixed 3-color palette (RGB):
            BLUE   = [0 0 1];
            YELLOW = [1 1 0];
            RED    = [1 0 0];


            % 3) Define two cut points on the 0..1 scale:
            %    0..1/3  -> Low  (blue)
            %    1/3..2/3-> Moderate (yellow)
            %    2/3..1  -> High (red)
            edges   = [0, 1/3, 2/3, 1];
            palette = [BLUE; YELLOW; RED];   % row 1=BLUE, row 2=YELLOW, row 3=RED

            % 4) Given a level in [0,1], pick BLUE/YELLOW/RED according to the ranges above.
            %    discretize(..., edges) returns 1/2/3 depending on which range the level is in.
            colorForLevel = @(lvl) palette(discretize(max(0,min(1,lvl)), edges), :);

            % 5) Apply discrete colors to the TOP row regions
            app.AmygdalaPatch.    FaceColor = colorForLevel(amyLevel);
            app.HippocampusPatch. FaceColor = colorForLevel(hipLevel);
            app.PFCPatch.         FaceColor = colorForLevel(pfcLevel);   % PFC note: red = strong control, blue = weak control.


            %% Compute Overall Anxiety Level
            % Anxiety level is based on hypothalamus activation level
            % Compute Overall Anxiety Level
            if forceHigh
                anxietyLevel = 'High';                          % hard rule wins
            else
                if hypothalamus_final_score < 1.8 %1.8 is threshold for low level of anxiety
                    anxietyLevel = 'Low';

                elseif hypothalamus_final_score < 3.5 %3.5 is threshold for moderate level of anxiety
                    anxietyLevel = 'Moderate';

                else
                    anxietyLevel = 'High';
                end

            end

            app.EvaluatedStressResponseTextArea.Value = ...
                ['Simulated Anxiety Level: ' anxietyLevel];

            switch anxietyLevel
                case 'Low'
                    app.HypothalamusPatch.FaceColor = BLUE;
                case 'Moderate'
                    app.HypothalamusPatch.FaceColor = YELLOW;
                otherwise  % 'High'
                    app.HypothalamusPatch.FaceColor = RED;
            end

            %% POPUP MODEL MAP
            if app.ShowMapCheckBox.Value
                % Close previous popup (keep only one window)
                if ~isempty(app.MapFigure) && isvalid(app.MapFigure)
                    delete(app.MapFigure);     % Close previous map window if it exists and is valid
                end

                % Create a new popup window and axes
                app.MapFigure = uifigure('Name','Model Map','Position',[100 100 560 560]);  % Create new popup window [x y width height]
                axM = uiaxes(app.MapFigure,'Position',[60 80 440 420]);      % Create axes inside the popup window
                title(axM,'Model Map (current settings)');
                xlabel(axM,'Intensity');
                ylabel(axM,'Duration');

                % Build a 10x10 label grid: 1=Low, 2=Moderate, 3=High
                G = zeros(10,10);
                for d = 1:10                          % Loop over duration values (rows)
                    for i = 1:10                      % Loop over intensity values (columns)
                % 1) Map raw sliders to scores 1..4 (same rules as the model)
                        if i<=3
                            iS=1;
                        elseif i<=5
                            iS=2;
                        elseif i<=7
                            iS=3;
                        else
                            iS=4;
                        end
                        if d<=3
                            dS=1;
                        elseif d<=5
                            dS=2;
                        elseif d<=7
                            dS=3;
                        else
                            dS=4;
                        end

                        % 2) Amygdala (weighted) then rounded
                        switch stimulusType
                            case 'Olfactory'
                                wI=0.7; wD=0.3;
                            case 'Auditory'
                                wI=0.3; wD=0.7;
                            otherwise
                                wI=0.5; wD=0.5; % Visual/default
                        end
                        amyg = round(wI*iS + wD*dS);    % Weighted amygdala response (rounded) 1..4

                        % 3) Hippocampus (discrete 0/1/2; familiar = scary memory)
                        if isFamiliar
                            s = iS + dS;
                            if s<=3
                                hip=0;
                            elseif s<=5
                                hip=1;
                            else
                                hip=2;
                            end
                    
                        else
                            hip=0;
                        end

                        % 4) PFC inhibition (0 / 0.2 / 0.3 / 0.5)
                        pI = 4 - iS;  pD = 4 - dS;
                        pReg = floor((pI + pD)/2);
                        if     pReg >  amyg
                            pfcLoc=0.5;              %pfc local inhibition
                        elseif pReg == amyg
                            pfcLoc=0.3;
                        elseif pReg == amyg-1
                            pfcLoc=0.2;
                        else
                            pfcLoc=0;
                        end

                        % 5) Hypothalamus base + clamp
                        hyp = amyg + hip - pfcLoc; 
                        if hyp<0
                            hyp=0;
                        end

                        % 6) Floor rule (exactly one at 10) -> at least Moderate (=2.0)
                        oneMax = ((i==10 || d==10) && ~(i==10 && d==10));
                        if oneMax
                            hyp = max(hyp, 2.0); 
                        end

                        % 7) Salience boost
                        Sal = max(iS,dS);    % Take the stronger of intensity/duration
                        if  oneMax
                            b=0.3;
                        elseif Sal==4
                            b=0.4;
                        elseif Sal==3
                            b=0.2;
                        else    
                            b=0;
                        end
                        hfs = hyp + b;   %HFS=hypo-final-score

                        % 8) Final label (1=Low, 2=Moderate, 3=High)
                        if i==10 && d==10
                            lab = 3;
                        elseif hfs < 1.8
                            lab = 1;
                        elseif hfs < 3.5
                            lab = 2;
                        else
                            lab = 3;
                        end

                        G(d,i) = lab;  % row = duration, col = intensity , Store label in the grid
                    end
                end

                % Draw the heatmap
                imagesc(axM, 1:10, 1:10, G);                        % Render matrix G as colored image
                colormap(axM, [0 0 1; 1 1 0; 1 0 0]);               % blue/yellow/red
                clim(axM, [1 3]);                                   % Set color limits to 1–3
                colorbar(axM, 'Ticks',[1 2 3], 'TickLabels',{'Low','Moderate','High'});   % Color legend
                axM.YDir = 'normal';                                % Flip Y-axis to normal orientation
                xticks(axM,1:10);
                yticks(axM,1:10);
                xlabel(axM,'Intensity'); 
                ylabel(axM,'Duration');
                title(axM,'Model Map (current settings)');

                % Mark the current selection on top of the map
                hold(axM,'on');                                         % Allow overlay without erasing heatmap
                plot(axM, intensity, duration, 'wo', 'MarkerSize', 10, 'LineWidth', 2); % white ring
                plot(axM, intensity, duration, 'k.', 'MarkerSize', 14);                  % black dot
                hold(axM,'off');

            else
                % If unchecked -> close popup if open
                if ~isempty(app.MapFigure) && isvalid(app.MapFigure)
                    delete(app.MapFigure);                         % Close popup if it exists
                end
                app.MapFigure = matlab.ui.Figure.empty;            % Reset to empty handle


            end


        end

        % Value changed function: ShowMapCheckBox
        function ShowMapCheckBoxValueChanged(app, event)
  % If unchecked, close any open popup immediately.
  if ~app.ShowMapCheckBox.Value
      if ~isempty(app.MapFigure) && isvalid(app.MapFigure)
          delete(app.MapFigure);
      end
      app.MapFigure = matlab.ui.Figure.empty;

      % If checked, the popup will be created on the next "Run".
      % (nothing happens immediately here – creation only occurs inside RunSimulationButtonPushed)
  end
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrainAxes = uiaxes(app.UIFigure);
            title(app.BrainAxes, 'BRAIN REGIONS')
            zlabel(app.BrainAxes, 'Z')
            app.BrainAxes.XTick = [];
            app.BrainAxes.YTick = [];
            app.stimulustypeLabel = uilabel(app.UIFigure);
            app.stimulustypeLabel.Text = 'Stimulus Type';
            app.StimulusTypeDropDown = uidropdown(app.UIFigure);
            app.StimulusTypeDropDown.Items = {'Auditory', 'Visual', 'Olfactory'};
            app.StimulusTypeDropDown.Value = 'Visual';
            app.IntensitylevelLabel = uilabel(app.UIFigure);
            app.IntensitylevelLabel.Text = 'Intensity';
            app.IntensitySlider = uislider(app.UIFigure);
            app.IntensitySlider.Limits = [0 10];
            app.IntensitySlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.DurationLabel = uilabel(app.UIFigure);
            app.DurationLabel.Text = 'Duration';
            app.DurationSlider = uislider(app.UIFigure);
            app.DurationSlider.Limits = [0 10];
            app.DurationSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.FamiliarCheckBox = uicheckbox(app.UIFigure);
            app.FamiliarCheckBox.Text = 'Familiar?';
            app.EvaluatedStressResponseLabel = uilabel(app.UIFigure);
            app.EvaluatedStressResponseLabel.Text = {'Evaluated'; ' Stress '; '   Response:	'};
            app.EvaluatedStressResponseTextArea = uitextarea(app.UIFigure);
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.AnxityLevelLabel = uilabel(app.UIFigure);
            app.AnxityLevelLabel.Text = 'Anxiety Level';
            app.AnxietyGauge = uigauge(app.UIFigure, 'semicircular');
            app.ShowMapCheckBox = uicheckbox(app.UIFigure);
            app.ShowMapCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowMapCheckBoxValueChanged, true);
            app.ShowMapCheckBox.Text = 'Show Map';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BrainAnxietySimulatorFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end